classdef Diatomix < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        Tree                     matlab.ui.container.CheckBoxTree
        RigidRotorNode           matlab.ui.container.TreeNode
        SpinSpinScalarNode       matlab.ui.container.TreeNode
        SpinSpinTensorNode       matlab.ui.container.TreeNode
        SpinRotationNode         matlab.ui.container.TreeNode
        NuclearElectricNode      matlab.ui.container.TreeNode
        PlotAdiabatsCheckBox     matlab.ui.control.CheckBox
        GridLayout3              matlab.ui.container.GridLayout
        EminECheck               matlab.ui.control.CheckBox
        IntensityDirField        matlab.ui.control.EditField
        EDirField                matlab.ui.control.EditField
        BDirField                matlab.ui.control.EditField
        FieldLabel               matlab.ui.control.Label
        IntensityEditFieldLabel  matlab.ui.control.Label
        EVcmLabel                matlab.ui.control.Label
        BGLabel                  matlab.ui.control.Label
        DirectionLabel           matlab.ui.control.Label
        BField                   matlab.ui.control.EditField
        EField                   matlab.ui.control.EditField
        IntensityField           matlab.ui.control.EditField
        UITable                  matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Ham % Hamiltonian
        Molecule
        UCBasis % Uncoupled Basis
        selectStates % States to show
    end
    
    
    methods (Access = private)
        
        function updatePlot(comp)
            ud = comp.UserData;
            ax = comp.UIAxes;

            delete(findobj(ax,'Type','line'))
            delete(findobj(ax,'Type','ConstantLine'))
            delete(findobj(ax,'Type','Scatter'))
            delete(findobj(ax,'Tag','Update'))
            % if strcmp(ud.xVar.name,"E")
            spec = ud.currSpec(:,ud.selectStates);
            if (comp.EminECheck.Value) && (strcmp(ud.xVar.name,"E"))
                spec = spec - mean(spec,2);
            end
            ps = plot(ax,ud.xVar.value,spec, ...
                'o-', "Color","k",MarkerSize=2, LineWidth=0.5, MarkerFaceColor="k");
            % for p=1:length(ps)
            %     ps(p).DataTipTemplate(1).DataTipRows(2).Label="E(MHz)=";
            % %     ps(p).DataTipTemplate(1).DataTipRows(3).Label="E(MHz)=";
            % end
            xlabel(ax,sprintf("%s (%s)",ud.xVar.name,ud.xVar.unit))
            xlim(ax,[min(ud.xVar.value),max(ud.xVar.value)]);
            legend(ax,'off');

            comp.UserData = ud;
        end
        function [adiabat, diabat] = findDiabats(comp, xIdx,yIdx)
            ud = comp.UserData;
            adiabat = ud.currSpec(:,yIdx); %follow xth energy state, stores energies
            adiabatStates = round(squeeze(ud.currStates(:,:,yIdx)).^2,4);
        
            diabatYidx = nan(1,length(ud.xVar.value)); %stores yIdx, not energy
            diabat = nan(1,length(ud.xVar.value)); %stores yIdx, not energy
        
            diabatYidx(xIdx) = yIdx;
            diabat(xIdx) = ud.currSpec(xIdx,yIdx);
            diabatStates = nan(size(adiabatStates));
            diabatStates(xIdx,:) = round(squeeze(ud.currStates(xIdx,:,yIdx)),4);
            [~, sIdx] = sortrows(diabatStates(xIdx,:)'.^2,"descend");
            for k=xIdx:(length(ud.xVar.value)-1) %forward loop
                stateNow = round(squeeze(ud.currStates(k,:,diabatYidx(k))).^2,4);
                statesNext = round(squeeze(ud.currStates(k+1,:,:)).^2,4);
                [~,idx]=max(sum((statesNext.*stateNow'),1)); %computes overlap integral
                diabatYidx(k+1) = idx;
                diabat(k+1) = ud.currSpec(k+1,idx);
                diabatStates(k+1,:) = round(squeeze(ud.currStates(k+1,:,idx)),4);
            end
            for k=(xIdx-1):-1:2 %backward loop
                stateIdx = diabatYidx(k+1);
                stateNow = round(squeeze(ud.currStates(k+1,:,stateIdx)).^2,4);
        %         stateSearch =stateIdx-1:stateIdx+1; stateSearch(stateSearch==0)=[];
                statesPrevious = round(squeeze(ud.currStates(k,:,:)).^2,4); %restrict to states one above and one below in energy
                [~,idx]=max(sum((statesPrevious.*stateNow'),1));
        %         idx = stateIdx +(idx-length(stateSearch)+1);
                diabatYidx(k) = idx;
                diabat(k) = ud.currSpec(k,idx);
                diabatStates(k,:) = round(squeeze(ud.currStates(k,:,idx)),4);
            end      
            % diabatStates = diabatStates(:,sIdx);
        end

        function calculateSpectrum(comp)
            C = Constants;
            ud = comp.UserData;
            Ham = comp.Ham;
            nStates = Ham.Basis.NStates;
            energies = nan(length(ud.xVar.value),nStates);
            states = nan(length(ud.xVar.value),nStates,nStates);
            E.dir = ud.E.dir;
            B.dir = ud.B.dir;
            for k = 1:length(ud.xVar.value)
                if strcmp(ud.xVar.name,"B")
                    E.value = ud.E.value(1)*ud.E.scale; 
                    B.value = ud.B.value(k)*ud.B.scale;
                    H = Ham.makeHamiltonian(E=E,B=B);
                elseif strcmp(ud.xVar.name,"E")
                    E.value = ud.E.value(k)*ud.E.scale;
                    B.value = ud.B.value(1)*ud.B.scale;
                    H = Ham.makeHamiltonian(E=E,B=B);
                else
                end
                [V,D] = eig(full(H/C.h),'vector'); %no need to use sparse matrices for matrices smaller than 1000x1000. Divide by Plancks constant to get energy in Hz
                [d,sortIdx] = sort(real(D));
                energies(k,:) = d;        
                states(k,:,:) = V(:,sortIdx);
            end
            ud.currStates = states;
            ud.currSpec = energies*1e-6; %energies in MHz (xVar,States)
            comp.UserData = ud;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialise(comp)
                % fig = comp.Parent;
                addpath("Atoms\")
                addpath("Molecules\")
                
                ud = comp.UserData;
                ud.B.name = "B"; ud.B.unit = "G"; ud.B.scale = 1e-4; %from Tesla to Gauss
                ud.E.name = "E"; ud.E.unit = "V/cm"; ud.E.scale = 1e2; %from V/m to V/cm
                ud.B.dir = eval(comp.BDirField.Value);
                ud.E.dir = eval(comp.EDirField.Value);
                ud.I.dir = eval(comp.IntensityDirField.Value);

                C = Constants;
                Molecule = KRb(40,87);
                Ham = Hamiltonian(Molecule = KRb(40,87));
                UCBasis = Ham.Basis;
                statesUCBasis = UCBasis.getStates('all');
                stateNames = ["N","mN","mi1","mi2"];
                ud.statesUCBasis = statesUCBasis(:,stateNames);
                comp.UITable.ColumnName = ["Comp",stateNames];
                selectN=0;
                ud.selectStates = ismember(statesUCBasis.N,selectN);
                comp.Ham = Ham; comp.Molecule = Molecule;
                collisionE = 0.1; %MHz
                comp.UserData = ud;

                FieldValueChanged(comp); %gets field values and updates plot
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(comp, event)
            ud = comp.UserData;
            ax = comp.UIAxes;
            delete(findobj(ax,'Tag','update'))
            
            %snap to nearest point and highlight
            [~,xIdx] = min(abs(event.IntersectionPoint(1)-ud.xVar.value));
            [~,yIdx] = min(abs(event.IntersectionPoint(2)-ud.currSpec(xIdx,:)));
            ax.NextPlot ="add";
            xline(ax,ud.xVar.value(xIdx),'--',color="k", Tag="update")
            yline(ax,ud.currSpec(xIdx,yIdx),'--',color="k",Tag="update")
            scatter(ax,ud.xVar.value(xIdx), ud.currSpec(xIdx,yIdx),50,'green','filled','o', MarkerFaceAlpha=0.5, Tag="update")
            
            %retrieve state composition of point and update table
            stateComp = round(squeeze(ud.currStates(xIdx,:,yIdx)).^2,4);
            [statesCompTable, ~] = sortrows([array2table(stateComp',"VariableNames","Comp") , ud.statesUCBasis], "Comp","descend");
            comp.UITable.Data = statesCompTable(1:20,:);

            %find diabat and adiabat and highlight 
            if comp.PlotAdiabatsCheckBox.Value
                [adiabat, diabat] = findDiabats(comp,xIdx,yIdx);
                diabatLine = plot(ax,ud.xVar.value, diabat, '--', Color="red", LineWidth=1.5, ...
                    DisplayName="Diabat",Tag="update");
                adiabatLine = plot(ax,ud.xVar.value, adiabat, '--', Color="blue",LineWidth=1.5, ...
                    DisplayName="Adiabat",Tag="update");
                legend(ax,[adiabatLine,diabatLine], Location="northeast");
            end
        end

        % Value changed function: BField, EField, IntensityField
        function FieldValueChanged(comp, event)
            ud = comp.UserData;
            try 
                ud.B.value = eval(comp.BField.Value);
                ud.E.value = eval(comp.EField.Value);
                % ud.I.value = eval(comp.comp.Intensity.Value);
                if length(ud.B.value)>1
                    ud.xVar = ud.B;
                elseif length(ud.E.value)>1
                    ud.xVar = ud.E;
                else
                    return
                end
            catch
                error("Not a valid entry for fields!")
                return
            end
            NPoints = length(ud.B.value)*length(ud.E.value);
            if NPoints > 250
                warning(sprintf("Do you really want to calculate %d points?",NPoints))
                return
            end
            comp.UserData = ud;
            calculateSpectrum(comp);
            updatePlot(comp);            
        end

        % Value changed function: BDirField, EDirField, IntensityDirField
        function FieldDirectionChanged(comp, event)
            ud = comp.UserData;
            % Bdir = eval(comp.BDirField.Value);
            Edir = eval(comp.EDirField.Value);
            Idir = eval(comp.IntensityDirField.Value);
            % assert(length(Bdir)==3 && sum(Bdir)~=0);
            assert(length(Edir)==3 && sum(Edir)~=0);
            assert(length(Idir)==3 && sum(Idir)~=0);

            % ud.B.dir=Bdir/norm(Bdir); 
            ud.E.dir=Edir/norm(Edir); 
            ud.I.dir=Idir/norm(Idir); 

            comp.UserData = ud;
            calculateSpectrum(comp);
            updatePlot(comp);   
        end

        % Value changed function: EminECheck
        function updatePlotCallback(comp, event)
            updatePlot(comp);   
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
           
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 918 559];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            xlabel(comp.UIAxes, 'X', 'Interpreter', 'latex')
            ylabel(comp.UIAxes, 'E (MHz)', 'Interpreter', 'latex')
            zlabel(comp.UIAxes, 'Z', 'Interpreter', 'latex')
            comp.UIAxes.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @UIAxesButtonDown, true);
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            comp.UITable.RowName = {};
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.IntensityField = uieditfield(comp.GridLayout3, 'text');
            comp.IntensityField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.IntensityField.Value = '0';
            comp.EField = uieditfield(comp.GridLayout3, 'text');
            comp.EField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.EField.Value = '0';
            comp.BField = uieditfield(comp.GridLayout3, 'text');
            comp.BField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.BField.Value = '0:50';
            comp.DirectionLabel = uilabel(comp.GridLayout3);
            comp.DirectionLabel.Text = 'Direction';
            comp.BGLabel = uilabel(comp.GridLayout3);
            comp.BGLabel.Text = 'B (G)';
            comp.EVcmLabel = uilabel(comp.GridLayout3);
            comp.EVcmLabel.Text = 'E (V/cm)';
            comp.IntensityEditFieldLabel = uilabel(comp.GridLayout3);
            comp.IntensityEditFieldLabel.Text = 'Intensity';
            comp.FieldLabel = uilabel(comp.GridLayout3);
            comp.FieldLabel.Text = 'Field';
            comp.BDirField = uieditfield(comp.GridLayout3, 'text');
            comp.BDirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.BDirField.Editable = 'off';
            comp.BDirField.Value = '[0,0,1]';
            comp.EDirField = uieditfield(comp.GridLayout3, 'text');
            comp.EDirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.EDirField.Value = '[0,1,0]';
            comp.IntensityDirField = uieditfield(comp.GridLayout3, 'text');
            comp.IntensityDirField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldDirectionChanged, true);
            comp.IntensityDirField.Value = '[0,0,1]';
            comp.EminECheck = uicheckbox(comp.GridLayout3);
            comp.EminECheck.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updatePlotCallback, true);
            comp.EminECheck.Text = 'E-<E>';
            comp.GridLayout4 = uigridlayout(comp.GridLayout2);
            comp.PlotAdiabatsCheckBox = uicheckbox(comp.GridLayout4);
            comp.PlotAdiabatsCheckBox.Text = 'Plot Adiabats';
            comp.Tree = uitree(comp.GridLayout4, 'checkbox');
            comp.RigidRotorNode = uitreenode(comp.Tree);
            comp.RigidRotorNode.Text = 'Rigid Rotor';
            comp.SpinSpinScalarNode = uitreenode(comp.Tree);
            comp.SpinSpinScalarNode.Text = 'Spin-Spin (Scalar)';
            comp.SpinSpinTensorNode = uitreenode(comp.Tree);
            comp.SpinSpinTensorNode.Text = 'Spin-Spin (Tensor)';
            comp.SpinRotationNode = uitreenode(comp.Tree);
            comp.SpinRotationNode.Text = 'Spin-Rotation';
            comp.NuclearElectricNode = uitreenode(comp.Tree);
            comp.NuclearElectricNode.Text = 'Nuclear Electric';
            % Assign Checked Nodes
            comp.Tree.CheckedNodes = [comp.RigidRotorNode, comp.SpinSpinScalarNode, comp.SpinSpinTensorNode, comp.SpinRotationNode, comp.NuclearElectricNode];
            % Execute the startup function
            initialise(comp)
        end
    end
end